classdef PlotKineGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        Theta1SliderLabel  matlab.ui.control.Label
        Theta1Slider       matlab.ui.control.Slider
        Theta2SliderLabel  matlab.ui.control.Label
        Theta2Slider       matlab.ui.control.Slider
        Theta3SliderLabel  matlab.ui.control.Label
        Theta3Slider       matlab.ui.control.Slider
    end


    properties (Access = private)
        length = [2.5,1.5,1]; % vector of link lengths
        handles % Description
    end

    methods (Access = private)
    
        function UIAxesUpdate(app)
            
            % get theta configuration from app sliders
            theta(1) = app.Theta1Slider.Value;
            theta(2) = app.Theta2Slider.Value;
            theta(3) = app.Theta3Slider.Value;
            
            % get lengths from length property and handles from handles property
            length = app.length;
            handles = app.handles;

            initPlot = false;
            if isempty(handles)
                handles.axes = app.UIAxes; % needed upon initialization of handles
                initPlot = true;
            end
            
            app.handles = plot3Link(handles,theta*pi/180,length,initPlot);

        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % get rid of border elements of figure
            set(app.UIAxes,...
                'xcolor',[1,1,1],...
                'xtick',[],...
                'xticklabel',[],...
                'ycolor',[1,1,1],...
                'ytick',[],...
                'yticklabel',[]);            
            UIAxesUpdate(app);
        end

        % Value changed function: Theta1Slider
        function Theta1SliderValueChanged(app, event)
            UIAxesUpdate(app);
        end

        % Value changed function: Theta2Slider
        function Theta2SliderValueChanged(app, event)
            UIAxesUpdate(app);            
        end

        % Value changed function: Theta3Slider
        function Theta3SliderValueChanged(app, event)
            UIAxesUpdate(app);           
        end

        % Value changing function: Theta1Slider
        function Theta1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Theta1Slider.Value = changingValue;
            UIAxesUpdate(app);
        end

        % Value changing function: Theta2Slider
        function Theta2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Theta2Slider.Value = changingValue;
            UIAxesUpdate(app);
        end

        % Value changing function: Theta3Slider
        function Theta3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Theta3Slider.Value = changingValue;
            UIAxesUpdate(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Serial RR')
            app.Theta1SliderLabel = uilabel(app.UIFigure);
            app.Theta1SliderLabel.Text = 'Theta 1';
            app.Theta1Slider = uislider(app.UIFigure);
            app.Theta1Slider.Limits = [-180 180];
            app.Theta1Slider.MajorTicks = [-180 -90 0 90 180];
            app.Theta1Slider.MajorTickLabels = {'', '', '', '', ''};
            app.Theta1Slider.ValueChangedFcn = createCallbackFcn(app, @Theta1SliderValueChanged, true);
            app.Theta1Slider.ValueChangingFcn = createCallbackFcn(app, @Theta1SliderValueChanging, true);
            app.Theta1Slider.MinorTicks = [];
            app.Theta2SliderLabel = uilabel(app.UIFigure);
            app.Theta2SliderLabel.Text = 'Theta 2';
            app.Theta2Slider = uislider(app.UIFigure);
            app.Theta2Slider.Limits = [-180 180];
            app.Theta2Slider.MajorTicks = [-180 -90 0 90 180];
            app.Theta2Slider.MajorTickLabels = {'', '', '', '', ''};
            app.Theta2Slider.ValueChangedFcn = createCallbackFcn(app, @Theta2SliderValueChanged, true);
            app.Theta2Slider.ValueChangingFcn = createCallbackFcn(app, @Theta2SliderValueChanging, true);
            app.Theta2Slider.MinorTicks = [];
            app.Theta3SliderLabel = uilabel(app.UIFigure);
            app.Theta3SliderLabel.Text = 'Theta 3';
            app.Theta3Slider = uislider(app.UIFigure);
            app.Theta3Slider.Limits = [-180 180];
            app.Theta3Slider.MajorTicks = [-180 -90 0 90 180];
            app.Theta3Slider.MajorTickLabels = {'', '', '', '', ''};
            app.Theta3Slider.ValueChangedFcn = createCallbackFcn(app, @Theta3SliderValueChanged, true);
            app.Theta3Slider.ValueChangingFcn = createCallbackFcn(app, @Theta3SliderValueChanging, true);
            app.Theta3Slider.MinorTicks = [];
        end
    end

    methods (Access = public)

        % Construct app
        function app = PlotKineGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end